classdef EBBPrac < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        GridLayout                 matlab.ui.container.GridLayout
        LeftPanel                  matlab.ui.container.Panel
        UpdateChannelsButton       matlab.ui.control.Button
        SamplingtoofastLabel       matlab.ui.control.Label
        BaudRateEditField          matlab.ui.control.NumericEditField
        BaudRateEditFieldLabel     matlab.ui.control.Label
        COMPortEditField           matlab.ui.control.EditField
        COMPortEditFieldLabel      matlab.ui.control.Label
        StopButton                 matlab.ui.control.StateButton
        StartButton                matlab.ui.control.StateButton
        DisturbanceEditField       matlab.ui.control.NumericEditField
        DisturbanceEditFieldLabel  matlab.ui.control.Label
        SetpointEditField          matlab.ui.control.NumericEditField
        SetpointEditFieldLabel     matlab.ui.control.Label
        OutputDropDown             matlab.ui.control.DropDown
        OutputDropDownLabel        matlab.ui.control.Label
        DEditField                 matlab.ui.control.NumericEditField
        DEditFieldLabel            matlab.ui.control.Label
        IEditField                 matlab.ui.control.NumericEditField
        IEditFieldLabel            matlab.ui.control.Label
        PEditField                 matlab.ui.control.NumericEditField
        PEditFieldLabel            matlab.ui.control.Label
        DutyCycleEditField         matlab.ui.control.NumericEditField
        DutyCycleEditFieldLabel    matlab.ui.control.Label
        RightPanel                 matlab.ui.container.Panel
        UIAxes_2                   matlab.ui.control.UIAxes
        UIAxes                     matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end


    properties (Access = private)
        %Live lines 1 and 2 are for the temperature measurements.
        liveLine1
        liveLine2
        %Live lines 3 and 4 are for the PWM signals.
        liveLine3
        liveLine4
        P
        I
        D
        setpoint
        disturbance
        duty_cycle
        output
        capturing
        COM_port
        baud_rate
        window_size
        esp32
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: PEditField
        function PEditFieldValueChanged(app, event)
            app.P = app.PEditField.Value;
        end

        % Value changed function: IEditField
        function IEditFieldValueChanged(app, event)
            app.I = app.IEditField.Value;
        end

        % Value changed function: DEditField
        function DEditFieldValueChanged(app, event)
            app.D = app.DEditField.Value;
        end

        % Value changed function: SetpointEditField
        function SetpointEditFieldValueChanged(app, event)
            app.setpoint = app.SetpointEditField.Value;
        end

        % Value changed function: DisturbanceEditField
        function DisturbanceEditFieldValueChanged(app, event)
            app.disturbance = app.DisturbanceEditField.Value;
        end

        % Value changed function: DutyCycleEditField
        function DutyCycleEditFieldValueChanged(app, event)
            app.duty_cycle = app.DutyCycleEditField.Value;
        end

        % Value changed function: OutputDropDown
        function OutputDropDownValueChanged(app, event)
            app.output = str2double(app.OutputDropDown.Value);
        end

        % Value changed function: StartButton
        function StartButtonValueChanged(app, event)
            app.StartButton.Visible = "off";
            app.StopButton.Visible = "on";
            app.UpdateChannelsButton.Visible = "on";
            app.capturing = true;

            %Call all of the other functions to make sure their values are
            %captured.
            app.PEditFieldValueChanged();
            app.IEditFieldValueChanged();
            app.DEditFieldValueChanged();
            app.SetpointEditFieldValueChanged();
            app.DisturbanceEditFieldValueChanged();
            app.DutyCycleEditFieldValueChanged();
            app.OutputDropDownValueChanged();
            app.BaudRateEditFieldValueChanged();
            app.COMPortEditFieldValueChanged();

            if isempty(app.esp32)
                app.esp32 = serialport(app.COM_port, app.baud_rate);
                configureTerminator(app.esp32, "LF");
            end
            flush(app.esp32);

            pause(1);

            %Make sure all info is read into esp.
            writeline(app.esp32, sprintf("%d, %.2f", 0, app.P)); %
            writeline(app.esp32, sprintf("%d, %.2f", 1, app.I)); %
            writeline(app.esp32, sprintf("%d, %.2f", 2, app.D)); %
            writeline(app.esp32, sprintf("%d, %.2f", 3, app.setpoint)); %
            writeline(app.esp32, sprintf("%d, %.2f", 4, app.disturbance)); %
            writeline(app.esp32, sprintf("%d, %d", 5, app.duty_cycle)); %
            writeline(app.esp32, sprintf("%d, %.2f", 6, app.output)); %
            writeline(app.esp32, sprintf("%d, %.2f", 7, app.capturing)); %

            %Clear axes.
            cla(app.UIAxes);
            cla(app.UIAxes_2);

            %Plotting axes for the temperature measurements.
            app.liveLine1 = animatedline(app.UIAxes, 'Color', 'r');
            app.liveLine2 = animatedline(app.UIAxes, 'Color', 'b');

            %Plotting axes for the PWM signals.
            app.liveLine3 = animatedline(app.UIAxes_2, 'Color', 'r');
            app.liveLine4 = animatedline(app.UIAxes_2, 'Color', 'b');

            app.window_size = 5000; %milliseconds

            while(app.capturing)
                if app.esp32.NumBytesAvailable > 0
                    rawData = readline(app.esp32);
                    data = str2double(split(rawData, ','));

                    if ~isnan(data(1)) && (length(data) == 6)
                        %Plot temperature data.
                        addpoints(app.liveLine1, data(5), data(1));
                        addpoints(app.liveLine2, data(5), data(2));
                        xlim(app.UIAxes, [data(5) - app.window_size, data(5)]);

                        %Plot PWM signals.
                        addpoints(app.liveLine3, data(5), data(3));
                        addpoints(app.liveLine4, data(5), data(4));
                        xlim(app.UIAxes_2, [data(5) - app.window_size, data(5)]);

                        if data(end) == 0
                            app.SamplingtoofastLabel.Visible = "on";
                        else
                            app.SamplingtoofastLabel.Visible = "off";
                        end
                    end

                    drawnow limitrate
                end
            end

            %Send stop signal.
            writeline(app.esp32, sprintf("%d, %.2f", 7, app.capturing));

            clear app.esp32;
        end

        % Value changed function: COMPortEditField
        function COMPortEditFieldValueChanged(app, event)
            app.COM_port = app.COMPortEditField.Value;
        end

        % Value changed function: BaudRateEditField
        function BaudRateEditFieldValueChanged(app, event)
            app.baud_rate = app.BaudRateEditField.Value;
        end

        % Value changed function: StopButton
        function StopButtonValueChanged(app, event)
            app.capturing = false;
            app.StopButton.Visible = "off";
            app.UpdateChannelsButton.Visible = "off";
            app.StartButton.Visible = "on";

        end

        % Button pushed function: UpdateChannelsButton
        function UpdateChannelsButtonPushed(app, event)
            app.DutyCycleEditFieldValueChanged();
            app.OutputDropDownValueChanged();
            writeline(app.esp32, sprintf("%d, %.2f", 5, app.duty_cycle));
            writeline(app.esp32, sprintf("%d, %.2f", 6, app.output));
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {449, 449};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {210, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.DutyCycleEditFieldLabel = uilabel(app.LeftPanel);
            app.DutyCycleEditFieldLabel.Text = 'Duty Cycle';
            app.DutyCycleEditField = uieditfield(app.LeftPanel, 'numeric');
            app.DutyCycleEditField.ValueChangedFcn = createCallbackFcn(app, @DutyCycleEditFieldValueChanged, true);
            app.DutyCycleEditField.Value = 50;
            app.PEditFieldLabel = uilabel(app.LeftPanel);
            app.PEditFieldLabel.Text = 'P';
            app.PEditField = uieditfield(app.LeftPanel, 'numeric');
            app.PEditField.ValueChangedFcn = createCallbackFcn(app, @PEditFieldValueChanged, true);
            app.IEditFieldLabel = uilabel(app.LeftPanel);
            app.IEditFieldLabel.Text = 'I';
            app.IEditField = uieditfield(app.LeftPanel, 'numeric');
            app.IEditField.ValueChangedFcn = createCallbackFcn(app, @IEditFieldValueChanged, true);
            app.DEditFieldLabel = uilabel(app.LeftPanel);
            app.DEditFieldLabel.Text = 'D';
            app.DEditField = uieditfield(app.LeftPanel, 'numeric');
            app.DEditField.ValueChangedFcn = createCallbackFcn(app, @DEditFieldValueChanged, true);
            app.OutputDropDownLabel = uilabel(app.LeftPanel);
            app.OutputDropDownLabel.Text = 'Output';
            app.OutputDropDown = uidropdown(app.LeftPanel);
            app.OutputDropDown.Items = {'None', 'Channel1', 'Channel2', 'Both'};
            app.OutputDropDown.ItemsData = {'3', '0', '1', '2'};
            app.OutputDropDown.ValueChangedFcn = createCallbackFcn(app, @OutputDropDownValueChanged, true);
            app.OutputDropDown.Value = '0';
            app.SetpointEditFieldLabel = uilabel(app.LeftPanel);
            app.SetpointEditFieldLabel.Text = 'Setpoint';
            app.SetpointEditField = uieditfield(app.LeftPanel, 'numeric');
            app.SetpointEditField.ValueChangedFcn = createCallbackFcn(app, @SetpointEditFieldValueChanged, true);
            app.DisturbanceEditFieldLabel = uilabel(app.LeftPanel);
            app.DisturbanceEditFieldLabel.Text = 'Disturbance';
            app.DisturbanceEditField = uieditfield(app.LeftPanel, 'numeric');
            app.DisturbanceEditField.ValueChangedFcn = createCallbackFcn(app, @DisturbanceEditFieldValueChanged, true);
            app.StartButton = uibutton(app.LeftPanel, 'state');
            app.StartButton.ValueChangedFcn = createCallbackFcn(app, @StartButtonValueChanged, true);
            app.StartButton.Text = 'Start';
            app.StopButton = uibutton(app.LeftPanel, 'state');
            app.StopButton.ValueChangedFcn = createCallbackFcn(app, @StopButtonValueChanged, true);
            app.StopButton.Visible = 'off';
            app.StopButton.Text = 'Stop';
            app.COMPortEditFieldLabel = uilabel(app.LeftPanel);
            app.COMPortEditFieldLabel.Text = 'COM Port';
            app.COMPortEditField = uieditfield(app.LeftPanel, 'text');
            app.COMPortEditField.ValueChangedFcn = createCallbackFcn(app, @COMPortEditFieldValueChanged, true);
            app.COMPortEditField.Value = '/dev/ttyUSB0';
            app.BaudRateEditFieldLabel = uilabel(app.LeftPanel);
            app.BaudRateEditFieldLabel.Text = 'Baud Rate';
            app.BaudRateEditField = uieditfield(app.LeftPanel, 'numeric');
            app.BaudRateEditField.ValueChangedFcn = createCallbackFcn(app, @BaudRateEditFieldValueChanged, true);
            app.BaudRateEditField.Value = 115200;
            app.SamplingtoofastLabel = uilabel(app.LeftPanel);
            app.SamplingtoofastLabel.Visible = 'off';
            app.SamplingtoofastLabel.Text = 'Sampling too fast!';
            app.UpdateChannelsButton = uibutton(app.LeftPanel, 'push');
            app.UpdateChannelsButton.ButtonPushedFcn = createCallbackFcn(app, @UpdateChannelsButtonPushed, true);
            app.UpdateChannelsButton.Visible = 'off';
            app.UpdateChannelsButton.Text = 'Update Channels';
            app.RightPanel = uipanel(app.GridLayout);
            app.UIAxes = uiaxes(app.RightPanel);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes_2 = uiaxes(app.RightPanel);
            title(app.UIAxes_2, 'Title')
            xlabel(app.UIAxes_2, 'X')
            ylabel(app.UIAxes_2, 'Y')
            zlabel(app.UIAxes_2, 'Z')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = EBBPrac

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end